classdef CONVET < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        Image                      matlab.ui.control.Image
        ELIMINARButton             matlab.ui.control.Button
        CONTINUARButton            matlab.ui.control.Button
        GRABARButton               matlab.ui.control.Button
        TuvozhadichoTextArea       matlab.ui.control.TextArea
        TuvozhadichoTextAreaLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        grabacionActiva = true; % Description
        StatusLabel
        recorder
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GRABARButton
        function GRABARButtonPushed(app, event)
            if ~isfield(app, 'grabacionActiva') || ~isfield(app, 'recorder') || isempty(app.recorder)
                % Inicializa las propiedades si no están definidas
                app.grabacionActiva = false;
                app.recorder = audiorecorder;
            end

            if app.grabacionActiva
                % Detener la grabación
                stop(app.recorder);
                app.grabacionActiva = false;
                app.StatusLabel.Text = 'Grabación detenida.';

                % Guardar el audio grabado si es necesario
                audioData = getaudiodata(app.recorder);
                audiowrite('grabacion_audio.wav', audioData, app.recorder.SampleRate);
            else
                % Iniciar la grabación
                record(app.recorder);
                app.grabacionActiva = true;
                app.StatusLabel.Text = 'Grabación iniciada.';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Voz')
            app.TuvozhadichoTextAreaLabel = uilabel(app.UIFigure);
            app.TuvozhadichoTextAreaLabel.Text = 'Tu voz ha dicho:';
            app.TuvozhadichoTextArea = uitextarea(app.UIFigure);
            app.GRABARButton = uibutton(app.UIFigure, 'push');
            app.GRABARButton.ButtonPushedFcn = createCallbackFcn(app, @GRABARButtonPushed, true);
            app.GRABARButton.Text = 'GRABAR';
            app.CONTINUARButton = uibutton(app.UIFigure, 'push');
            app.CONTINUARButton.Text = 'CONTINUAR';
            app.ELIMINARButton = uibutton(app.UIFigure, 'push');
            app.ELIMINARButton.Text = 'ELIMINAR';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'IPN.png');
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UPIIT.jpg');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CONVET

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end